classdef FW_1D_Time_Series_Analyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        T_INTVMEANEditField             matlab.ui.control.NumericEditField
        T_INTVMEANEditFieldLabel        matlab.ui.control.Label
        WAVEMAKEREditField              matlab.ui.control.EditField
        WAVEMAKEREditFieldLabel         matlab.ui.control.Label
        DIFFUSIONSPONGEEditField        matlab.ui.control.EditField
        DIFFUSIONSPONGEEditField_2Label  matlab.ui.control.Label
        Cbrk2                           matlab.ui.control.NumericEditField
        Cbrk2EditFieldLabel             matlab.ui.control.Label
        Cbrk1                           matlab.ui.control.NumericEditField
        Cbrk1EditFieldLabel             matlab.ui.control.Label
        Cd                              matlab.ui.control.NumericEditField
        CdEditFieldLabel                matlab.ui.control.Label
        LineLabel                       matlab.ui.control.Label
        SpongeDimensionsLabel           matlab.ui.control.Label
        NEditField                      matlab.ui.control.NumericEditField
        NEditFieldLabel                 matlab.ui.control.Label
        SEditField                      matlab.ui.control.NumericEditField
        SEditFieldLabel                 matlab.ui.control.Label
        EEditField                      matlab.ui.control.NumericEditField
        EEditFieldLabel                 matlab.ui.control.Label
        WEditField                      matlab.ui.control.NumericEditField
        WEditFieldLabel                 matlab.ui.control.Label
        CspEditField                    matlab.ui.control.NumericEditField
        CspEditFieldLabel               matlab.ui.control.Label
        CDSpongeEditField               matlab.ui.control.NumericEditField
        CDSpongeEditFieldLabel          matlab.ui.control.Label
        DIRECTSPONGEEditField           matlab.ui.control.EditField
        DIRECTSPONGEEditFieldLabel      matlab.ui.control.Label
        FRICTIONSPONGEEditField         matlab.ui.control.EditField
        FRICTIONSPONGEEditFieldLabel    matlab.ui.control.Label
        VISCOSITYBREAKINGEditField      matlab.ui.control.EditField
        VISCOSITYBREAKINGEditFieldLabel  matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        DEP_WKEditField                 matlab.ui.control.NumericEditField
        DEP_WKEditFieldLabel            matlab.ui.control.Label
        Theta_WKEditField               matlab.ui.control.NumericEditField
        Theta_WKEditFieldLabel          matlab.ui.control.Label
        Xc_WKEditField                  matlab.ui.control.NumericEditField
        Xc_WKEditFieldLabel             matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        MinDepthEditField               matlab.ui.control.NumericEditField
        MinDepthEditFieldLabel          matlab.ui.control.Label
        FroudeCapEditField              matlab.ui.control.NumericEditField
        FroudeCapEditFieldLabel         matlab.ui.control.Label
        SCREEN_INTVEditField            matlab.ui.control.NumericEditField
        SCREEN_INTVEditFieldLabel       matlab.ui.control.Label
        PLOT_INTVEditField              matlab.ui.control.NumericEditField
        PLOT_INTVEditFieldLabel         matlab.ui.control.Label
        CFLEditField                    matlab.ui.control.NumericEditField
        CFLEditFieldLabel               matlab.ui.control.Label
        TOTAL_TIMEEditField             matlab.ui.control.NumericEditField
        TOTAL_TIMEEditFieldLabel        matlab.ui.control.Label
        PYEditField                     matlab.ui.control.NumericEditField
        PYEditFieldLabel                matlab.ui.control.Label
        PXEditField                     matlab.ui.control.NumericEditField
        PXEditFieldLabel                matlab.ui.control.Label
        DYEditField                     matlab.ui.control.NumericEditField
        DYEditFieldLabel                matlab.ui.control.Label
        DXEditField                     matlab.ui.control.NumericEditField
        DXEditFieldLabel                matlab.ui.control.Label
        NglobEditField                  matlab.ui.control.NumericEditField
        NglobEditFieldLabel             matlab.ui.control.Label
        MglobEditField                  matlab.ui.control.NumericEditField
        MglobEditFieldLabel             matlab.ui.control.Label
        FunwaveConstantParametersLabel  matlab.ui.control.Label
        FolderSelectedEditField         matlab.ui.control.EditField
        FolderSelectedEditFieldLabel    matlab.ui.control.Label
        LoadinDataLabel                 matlab.ui.control.Label
        PointtoPlotEditField            matlab.ui.control.NumericEditField
        PointtoPlotEditFieldLabel       matlab.ui.control.Label
        LoadDataSelectFolderButton      matlab.ui.control.Button
        FunwaveVariableParametersLabel  matlab.ui.control.Label
        TPERIODEditField                matlab.ui.control.NumericEditField
        TPERIODEditFieldLabel           matlab.ui.control.Label
        AMP_WKEditField                 matlab.ui.control.NumericEditField
        AMP_WKEditFieldLabel            matlab.ui.control.Label
        SLPEditField                    matlab.ui.control.NumericEditField
        SLPEditFieldLabel               matlab.ui.control.Label
        AnimateButton                   matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        BathyGraph                      matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        % Data
        NPY 
        sumvars
        sumconst

        % Variables
        SLP=0.05    
        AMPWK=1
        TPERIOD=9

        % Point in the model domain to plot
        point=900
        
    end
    
    methods (Access = private)
        
        function data = readNPY(app,filename)
        % Function to read NPY files into matlab.
        % *** Only reads a subset of all possible NPY files, specifically N-D arrays of certain data types.
        % See https://github.com/kwikteam/npy-matlab/blob/master/tests/npy.ipynb for
        % more.
        %
        
        [shape, dataType, fortranOrder, littleEndian, totalHeaderLength, ~] = readNPYheader(app,filename);
        
        if littleEndian
            fid = fopen(filename, 'r', 'l');
        else
            fid = fopen(filename, 'r', 'b');
        end
        
        try
        
            [~] = fread(fid, totalHeaderLength, 'uint8');
        
            % read the data
            data = fread(fid, prod(shape), [dataType '=>' dataType]);
        
            if length(shape)>1 && ~fortranOrder
                data = reshape(data, shape(end:-1:1));
                data = permute(data, [length(shape):-1:1]);
            elseif length(shape)>1
                data = reshape(data, shape);
            end
        
            fclose(fid);
        
        catch me
            fclose(fid);
            rethrow(me);
        end
        end

        function [arrayShape, dataType, fortranOrder, littleEndian, totalHeaderLength, npyVersion] = readNPYheader(app,filename)
            % function [arrayShape, dataType, fortranOrder, littleEndian, ...
            %       totalHeaderLength, npyVersion] = readNPYheader(filename)
            %
            % parse the header of a .npy file and return all the info contained
            % therein.
            %
            % Based on spec at http://docs.scipy.org/doc/numpy-dev/neps/npy-format.html
            
            fid = fopen(filename);
            
            % verify that the file exists
            if (fid == -1)
                if ~isempty(dir(filename))
                    error('Permission denied: %s', filename);
                else
                    error('File not found: %s', filename);
                end
            end
            
            try
                
                dtypesMatlab = {'uint8','uint16','uint32','uint64','int8','int16','int32','int64','single','double', 'logical'};
                dtypesNPY = {'u1', 'u2', 'u4', 'u8', 'i1', 'i2', 'i4', 'i8', 'f4', 'f8', 'b1'};
                
                
                magicString = fread(fid, [1 6], 'uint8=>uint8');
                
                if ~all(magicString == [147,78,85,77,80,89])
                    error('readNPY:NotNUMPYFile', 'Error: This file does not appear to be NUMPY format based on the header.');
                end
                
                majorVersion = fread(fid, [1 1], 'uint8=>uint8');
                minorVersion = fread(fid, [1 1], 'uint8=>uint8');
                
                npyVersion = [majorVersion minorVersion];
                
                headerLength = fread(fid, [1 1], 'uint16=>uint16');
                
                totalHeaderLength = 10+headerLength;
                
                arrayFormat = fread(fid, [1 headerLength], 'char=>char');
                
                % to interpret the array format info, we make some fairly strict
                % assumptions about its format...
                
                r = regexp(arrayFormat, '''descr''\s*:\s*''(.*?)''', 'tokens');
                if isempty(r)
                    error('Couldn''t parse array format: "%s"', arrayFormat);
                end
                dtNPY = r{1}{1};    
                
                littleEndian = ~strcmp(dtNPY(1), '>');
                
                dataType = dtypesMatlab{strcmp(dtNPY(2:3), dtypesNPY)};
                    
                r = regexp(arrayFormat, '''fortran_order''\s*:\s*(\w+)', 'tokens');
                fortranOrder = strcmp(r{1}{1}, 'True');
                
                r = regexp(arrayFormat, '''shape''\s*:\s*\((.*?)\)', 'tokens');
                shapeStr = r{1}{1}; 
                arrayShape = str2num(shapeStr(shapeStr~='L'));
            
                
                fclose(fid);
                
            catch me
                fclose(fid);
                rethrow(me);
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
              screenSize = get(groot,'ScreenSize');
              screenWidth = screenSize(3);
              screenHeight = screenSize(4);
              left = screenWidth*0.1;
              bottom = screenHeight*0.05;
              width = screenWidth*0.85;
              height = screenHeight*0.875;
              drawnow;
              app.UIFigure.Position = [left bottom width height]; 
        end

        % Value changed function: SLPEditField
        function SLPEditFieldValueChanged(app, event)
            value = app.SLPEditField.Value;
            app.SLP = value;
        end

        % Value changed function: AMP_WKEditField
        function AMP_WKEditFieldValueChanged(app, event)
            value = app.AMP_WKEditField.Value;
            app.AMPWK = value;
        end

        % Value changed function: TPERIODEditField
        function TPERIODEditFieldValueChanged(app, event)
            value = app.TPERIODEditField.Value;
            app.TPERIOD = value;
        end

        % Button pushed function: AnimateButton
        function AnimateButtonPushed(app, event)

            % Pull out sumvars table for ease
            sumvarsh = app.sumvars;
            sumconsth = app.sumconst; 

            % Find which trial best matches the input values
            distances = sqrt((sumvarsh.AMP_WK - app.AMPWK).^2 + ...
                 (sumvarsh.Tperiod - app.TPERIOD).^2 + ...
                 (sumvarsh.SLP - app.SLP).^2);
            [~, ind] = min(distances);


            %%% Plot for Time Series
            % Plot the time series
            %
            plot(app.UIAxes,squeeze(app.NPY(ind,:,app.point)))

            % Pull out the Funwave variables for labels
                SLP_s = num2str(app.sumvars{ind,'SLP'});
                AMP_WK_s = num2str(app.sumvars{ind,'AMP_WK'});
                Tperiod_s = num2str(app.sumvars{ind,'Tperiod'});
                Xslp_s = num2str(app.sumvars{ind,'Xslp'});
                titlename = {['SLP = ', SLP_s], ...
                    ['AMP_WK = ', AMP_WK_s],...
                    ['Tperiod = ', Tperiod_s],...
                    ['Xslp = ', Xslp_s]};
                title(app.UIAxes, titlename)

            % Plot Labels
                grid(app.UIAxes,'on')
                xlabel(app.UIAxes, 'time (s)');
                ylabel(app.UIAxes, '\eta');

            
            %%% Plot for Bathymetry
            % Pull out relevant variables
            Xslp = app.sumvars{ind,'Xslp'};
            dep = -sumconsth.DEPTH_FLAT;
            Xc_WK = sumconsth.Xc_WK;
            Sp_W = sumconsth.Sponge_west_width;

            cla(app.BathyGraph) 
            hold(app.BathyGraph,"on")
            plot(app.BathyGraph,[0,Xslp],[dep,dep],'Color','k','LineWidth',2)
            plot(app.BathyGraph,[Xslp,1024],[dep,0],'Color','k','LineWidth',2)
            scatter(app.BathyGraph,Xslp,dep, 'filled', 'o','MarkerEdgeColor','m','MarkerFaceColor','m');
            xline(app.BathyGraph,Sp_W,'Color','r','LineWidth',2, 'LineStyle','--');
            xline(app.BathyGraph,Xc_WK,'Color','b','LineWidth',2, 'LineStyle','--');
            grid(app.BathyGraph,'on')
            legend(app.BathyGraph,'','','Xslp','Sponge Layer', 'WaveMaker', 'Location','northwest');
            

        end

        % Button pushed function: LoadDataSelectFolderButton
        function LoadDataSelectFolderButtonPushed(app, event)
            %[filename,path] = uigetfile();
            selpath = uigetdir();

            % Save paths to data
            app.NPY = readNPY(app,fullfile(selpath,'data_1D.npy'));
            app.sumvars = readtable(fullfile(selpath,'sumvars.txt'));
            app.sumconst = readtable(fullfile(selpath,'sumconst.txt'));
            app.FolderSelectedEditField.Value = selpath;

            % Load in Constants
            sumconsth = app.sumconst;
                app.MglobEditField.Value = sumconsth.Mglob;
                app.NglobEditField.Value = sumconsth.Nglob;

                app.DXEditField.Value = sumconsth.DX;
                app.DYEditField.Value = sumconsth.DY;

                app.PXEditField.Value = sumconsth.PX;
                app.PYEditField.Value = sumconsth.PY;

                app.TOTAL_TIMEEditField.Value = sumconsth.TOTAL_TIME;
                app.PLOT_INTVEditField.Value = sumconsth.PLOT_INTV;
                app.SCREEN_INTVEditField.Value = sumconsth.SCREEN_INTV;

                app.CFLEditField.Value = sumconsth.CFL;
                app.FroudeCapEditField.Value= sumconsth.FroudeCap;
                app.MinDepthEditField.Value= sumconsth.MinDepth;

                app.WAVEMAKEREditField.Value = sumconsth.WAVEMAKER{1};
                app.DEP_WKEditField.Value = sumconsth.DEP_WK;
                app.Xc_WKEditField.Value = sumconsth.Xc_WK;
                app.Theta_WKEditField.Value = sumconsth.Theta_WK;
                app.DIFFUSIONSPONGEEditField.Value = sumconsth.DIFFUSION_SPONGE{1};
                app.FRICTIONSPONGEEditField.Value = sumconsth.FRICTION_SPONGE{1};
                app.DIRECTSPONGEEditField.Value = sumconsth.DIRECT_SPONGE{1};

                app.CspEditField.Value = sumconsth.Csp;
                app.CDSpongeEditField.Value = sumconsth.CDsponge;

                app.SEditField.Value = sumconsth.Sponge_south_width;
                app.NEditField.Value = sumconsth.Sponge_north_width;
                app.WEditField.Value = sumconsth.Sponge_west_width;
                app.EEditField.Value = sumconsth.Sponge_east_width;

                app.VISCOSITYBREAKINGEditField.Value = sumconsth.VISCOSITY_BREAKING{1};
                app.T_INTVMEANEditField.Value = sumconsth.T_INTV_mean;


        end

        % Value changed function: PointtoPlotEditField
        function PointtoPlotEditFieldValueChanged(app, event)
            value = app.PointtoPlotEditField.Value;
            app.point = value;
        end

        % Value changed function: FolderSelectedEditField
        function FolderSelectedEditFieldValueChanged(app, event)
            value = app.FolderSelectedEditField.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {861, 861};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {292, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.AnimateButton = uibutton(app.LeftPanel, 'push');
            app.AnimateButton.ButtonPushedFcn = createCallbackFcn(app, @AnimateButtonPushed, true);
            app.AnimateButton.Text = 'Animate';
            app.SLPEditFieldLabel = uilabel(app.LeftPanel);
            app.SLPEditFieldLabel.Text = 'SLP';
            app.SLPEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SLPEditField.ValueChangedFcn = createCallbackFcn(app, @SLPEditFieldValueChanged, true);
            app.SLPEditField.Value = 0.05;
            app.AMP_WKEditFieldLabel = uilabel(app.LeftPanel);
            app.AMP_WKEditFieldLabel.Text = 'AMP_WK';
            app.AMP_WKEditField = uieditfield(app.LeftPanel, 'numeric');
            app.AMP_WKEditField.ValueChangedFcn = createCallbackFcn(app, @AMP_WKEditFieldValueChanged, true);
            app.AMP_WKEditField.Value = 1;
            app.TPERIODEditFieldLabel = uilabel(app.LeftPanel);
            app.TPERIODEditFieldLabel.Text = 'TPERIOD';
            app.TPERIODEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TPERIODEditField.ValueChangedFcn = createCallbackFcn(app, @TPERIODEditFieldValueChanged, true);
            app.TPERIODEditField.Value = 9;
            app.FunwaveVariableParametersLabel = uilabel(app.LeftPanel);
            app.FunwaveVariableParametersLabel.Text = 'Funwave Variable Parameters';
            app.LoadDataSelectFolderButton = uibutton(app.LeftPanel, 'push');
            app.LoadDataSelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataSelectFolderButtonPushed, true);
            app.LoadDataSelectFolderButton.Text = 'Load Data: Select Folder';
            app.PointtoPlotEditFieldLabel = uilabel(app.LeftPanel);
            app.PointtoPlotEditFieldLabel.Text = 'Point to Plot';
            app.PointtoPlotEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PointtoPlotEditField.ValueChangedFcn = createCallbackFcn(app, @PointtoPlotEditFieldValueChanged, true);
            app.PointtoPlotEditField.Value = 900;
            app.LoadinDataLabel = uilabel(app.LeftPanel);
            app.LoadinDataLabel.Text = 'Load in Data';
            app.FolderSelectedEditFieldLabel = uilabel(app.LeftPanel);
            app.FolderSelectedEditFieldLabel.Text = 'Folder Selected';
            app.FolderSelectedEditField = uieditfield(app.LeftPanel, 'text');
            app.FolderSelectedEditField.ValueChangedFcn = createCallbackFcn(app, @FolderSelectedEditFieldValueChanged, true);
            app.FunwaveConstantParametersLabel = uilabel(app.LeftPanel);
            app.FunwaveConstantParametersLabel.Text = 'Funwave Constant Parameters';
            app.MglobEditFieldLabel = uilabel(app.LeftPanel);
            app.MglobEditFieldLabel.Text = 'Mglob';
            app.MglobEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NglobEditFieldLabel = uilabel(app.LeftPanel);
            app.NglobEditFieldLabel.Text = 'Nglob';
            app.NglobEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DXEditFieldLabel = uilabel(app.LeftPanel);
            app.DXEditFieldLabel.Text = 'DX';
            app.DXEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DYEditFieldLabel = uilabel(app.LeftPanel);
            app.DYEditFieldLabel.Text = 'DY';
            app.DYEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PXEditFieldLabel = uilabel(app.LeftPanel);
            app.PXEditFieldLabel.Text = 'PX';
            app.PXEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PYEditFieldLabel = uilabel(app.LeftPanel);
            app.PYEditFieldLabel.Text = 'PY';
            app.PYEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TOTAL_TIMEEditFieldLabel = uilabel(app.LeftPanel);
            app.TOTAL_TIMEEditFieldLabel.Text = 'TOTAL_TIME';
            app.TOTAL_TIMEEditField = uieditfield(app.LeftPanel, 'numeric');
            app.CFLEditFieldLabel = uilabel(app.LeftPanel);
            app.CFLEditFieldLabel.Text = 'CFL';
            app.CFLEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PLOT_INTVEditFieldLabel = uilabel(app.LeftPanel);
            app.PLOT_INTVEditFieldLabel.Text = 'PLOT_INTV';
            app.PLOT_INTVEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SCREEN_INTVEditFieldLabel = uilabel(app.LeftPanel);
            app.SCREEN_INTVEditFieldLabel.Text = 'SCREEN_INTV';
            app.SCREEN_INTVEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FroudeCapEditFieldLabel = uilabel(app.LeftPanel);
            app.FroudeCapEditFieldLabel.Text = 'FroudeCap';
            app.FroudeCapEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MinDepthEditFieldLabel = uilabel(app.LeftPanel);
            app.MinDepthEditFieldLabel.Text = 'MinDepth';
            app.MinDepthEditField = uieditfield(app.LeftPanel, 'numeric');
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '______________________________';
            app.Label_3 = uilabel(app.LeftPanel);
            app.Label_3.Text = '______________________________';
            app.Xc_WKEditFieldLabel = uilabel(app.LeftPanel);
            app.Xc_WKEditFieldLabel.Text = 'Xc_WK';
            app.Xc_WKEditField = uieditfield(app.LeftPanel, 'numeric');
            app.Theta_WKEditFieldLabel = uilabel(app.LeftPanel);
            app.Theta_WKEditFieldLabel.Text = 'Theta_WK';
            app.Theta_WKEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DEP_WKEditFieldLabel = uilabel(app.LeftPanel);
            app.DEP_WKEditFieldLabel.Text = 'DEP_WK';
            app.DEP_WKEditField = uieditfield(app.LeftPanel, 'numeric');
            app.Label_4 = uilabel(app.LeftPanel);
            app.Label_4.Text = '______________________________';
            app.VISCOSITYBREAKINGEditFieldLabel = uilabel(app.LeftPanel);
            app.VISCOSITYBREAKINGEditFieldLabel.Text = {'VISCOSITY'; 'BREAKING'};
            app.VISCOSITYBREAKINGEditField = uieditfield(app.LeftPanel, 'text');
            app.FRICTIONSPONGEEditFieldLabel = uilabel(app.LeftPanel);
            app.FRICTIONSPONGEEditFieldLabel.Text = {'FRICTION '; 'SPONGE'};
            app.FRICTIONSPONGEEditField = uieditfield(app.LeftPanel, 'text');
            app.DIRECTSPONGEEditFieldLabel = uilabel(app.LeftPanel);
            app.DIRECTSPONGEEditFieldLabel.Text = {'DIRECT'; 'SPONGE'};
            app.DIRECTSPONGEEditField = uieditfield(app.LeftPanel, 'text');
            app.CDSpongeEditFieldLabel = uilabel(app.LeftPanel);
            app.CDSpongeEditFieldLabel.Text = 'CDSponge';
            app.CDSpongeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.CspEditFieldLabel = uilabel(app.LeftPanel);
            app.CspEditFieldLabel.Text = 'Csp';
            app.CspEditField = uieditfield(app.LeftPanel, 'numeric');
            app.WEditFieldLabel = uilabel(app.LeftPanel);
            app.WEditFieldLabel.Text = 'W';
            app.WEditField = uieditfield(app.LeftPanel, 'numeric');
            app.EEditFieldLabel = uilabel(app.LeftPanel);
            app.EEditFieldLabel.Text = 'E';
            app.EEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SEditFieldLabel = uilabel(app.LeftPanel);
            app.SEditFieldLabel.Text = 'S';
            app.SEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NEditFieldLabel = uilabel(app.LeftPanel);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SpongeDimensionsLabel = uilabel(app.LeftPanel);
            app.SpongeDimensionsLabel.Text = 'Sponge Dimensions';
            app.LineLabel = uilabel(app.LeftPanel);
            app.LineLabel.Text = '______________________________';
            app.CdEditFieldLabel = uilabel(app.LeftPanel);
            app.CdEditFieldLabel.Text = 'Cd';
            app.Cd = uieditfield(app.LeftPanel, 'numeric');
            app.Cbrk1EditFieldLabel = uilabel(app.LeftPanel);
            app.Cbrk1EditFieldLabel.Text = 'Cbrk1';
            app.Cbrk1 = uieditfield(app.LeftPanel, 'numeric');
            app.Cbrk2EditFieldLabel = uilabel(app.LeftPanel);
            app.Cbrk2EditFieldLabel.Text = 'Cbrk2';
            app.Cbrk2 = uieditfield(app.LeftPanel, 'numeric');
            app.DIFFUSIONSPONGEEditField_2Label = uilabel(app.LeftPanel);
            app.DIFFUSIONSPONGEEditField_2Label.Text = {'DIFFUSION'; 'SPONGE'};
            app.DIFFUSIONSPONGEEditField = uieditfield(app.LeftPanel, 'text');
            app.WAVEMAKEREditFieldLabel = uilabel(app.LeftPanel);
            app.WAVEMAKEREditFieldLabel.Text = {'WAVE'; 'MAKER'};
            app.WAVEMAKEREditField = uieditfield(app.LeftPanel, 'text');
            app.T_INTVMEANEditFieldLabel = uilabel(app.LeftPanel);
            app.T_INTVMEANEditFieldLabel.Text = {'T_INTV'; 'MEAN'};
            app.T_INTVMEANEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.BathyGraph = uiaxes(app.RightPanel);
            title(app.BathyGraph, 'Associated Bathymetry')
            xlabel(app.BathyGraph, 'X')
            ylabel(app.BathyGraph, 'Y')
            zlabel(app.BathyGraph, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FW_1D_Time_Series_Analyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end